classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClicktoprocessimageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClicktoprocessimageButton
        function ClicktoprocessimageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg; *.tif; *.png;*.gif; *.jpeg'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
            
            function updateimage(~, image)
                imshow(image);
                I_tresh=im2bw(im); %by replacing all pixels in the input image with luminance greater than level with the value 1 (white) and replacing all other pixels with the value 0 (black).
                imshow(I_tresh);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ClicktoprocessimageButton = uibutton(app.UIFigure, 'push');
            app.ClicktoprocessimageButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoprocessimageButtonPushed, true);
            app.ClicktoprocessimageButton.Text = 'Click to process image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end